classdef ArmElevation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ArmElev                     matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        ExitMenu                    matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        Panel                       matlab.ui.container.Panel
        LightThresholdluxEditField  matlab.ui.control.NumericEditField
        LightThresholdluxLabel      matlab.ui.control.Label
        LowPassHzEditField          matlab.ui.control.NumericEditField
        LowPassHzLabel              matlab.ui.control.Label
        Zoomto10minButton           matlab.ui.control.Button
        StatusLabel                 matlab.ui.control.Label
        Zoomto30sButton             matlab.ui.control.Button
        ResetZoomButton             matlab.ui.control.Button
        UITable2                    matlab.ui.control.Table
        LoaddataButton              matlab.ui.control.Button
        DeleteActivityButton        matlab.ui.control.Button
        AddNextActivityButton       matlab.ui.control.Button
        DefineStartEndandZeroReferencePanel  matlab.ui.container.Panel
        ActivityLabel               matlab.ui.control.Label
        MarkZeroReferenceButton     matlab.ui.control.Button
        MarkerEditField             matlab.ui.control.EditField
        MarkerEditFieldLabel        matlab.ui.control.Label
        MarkEndButton               matlab.ui.control.Button
        MarkStartButton             matlab.ui.control.Button
        AxivityDataEditField        matlab.ui.control.EditField
        AxivityDataEditFieldLabel   matlab.ui.control.Label
        CalculationandExportPanel   matlab.ui.container.Panel
        PlotDropDown                matlab.ui.control.DropDown
        PlotDropDownLabel           matlab.ui.control.Label
        ExportElevationDataButton   matlab.ui.control.Button
        ExportSummaryButton         matlab.ui.control.Button
        CalculateButton             matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        OpenfileButton              matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Copyright @2020 Pasan Hettiarachchi
    % This source code is released under    GNU LESSER GENERAL PUBLIC LICENSE, Version 3.0
    % See: https://www.gnu.org/licenses/lgpl-3.0.en.html

    
    properties (Access = private)
        % Description
        logFile % the handle to log file to be used in logging errors
        DeviceID;% the deviceID of loaded axivity data file
        cwadata;% structure containg all axivity data loaded (Acc, TEMP, LIGHT, BATT)
        
        ftype=1; % the file type (CWA or WAV of the file opened)
        Fs=25; % default sample rate. Will be corrected later
        filtF=4; % the low-pass filter cutoff frequency
        pts_1s=5; % plot only a limit number of pts per sec
        startTime; % the start time of the whole axivity data file
        stopTime; % the stop time of whole axivity data file
        
        t_start; % the start time of the loaded axivity data
        t_end; % the end time of the loaded axivity data
        
        selStrTime; % manually specified start time (for trimming large data file)
        selStpTime; % manually specified stop time (for trimming large data file)
        
        t_duration % the time duration of the loaded axivity data
        tdatetime=[]; % the time axis as a datetime array of loaded axivity data
        selectedRow=1; % the row selected in the table. Used for start-end picking etc
        dotline=[]; % the handle for the dotted-line cursor
        lightPks=[]; % the handle for the dotted-line cursor
        shades=[]; % the array to hold activity shades
        shd_texts=[];% the array to hold activity shades titles
        cwainfo; % info structure from reading CWA file
        
        cwafile=''; % the full data filename
        normAcc=[]; % the normalised 3-D acceleration vector
        Sections={}; % the structure containing data of different activities
        
        dataLoaded=false; % the dataloaded flag
        Calculated=false;
        abt_fig;
        
    end
    
    methods (Access = private)
        
        function drawline(app,~,event)
            
            switch event.EventName
                case 'Hit'
                    % Properties: Btn/IntersectionPoint/Src/Evnt
                    cp=get(app.UIAxes,'CurrentPoint');
                    if ~isempty(app.dotline)
                        delete(app.dotline);
                    end
                    x_pos=num2ruler(cp(1),app.UIAxes.XAxis);
                    app.dotline=xline(app.UIAxes,x_pos, '--g', 'Linewidth', 1.2,'DisplayName','Marker');
                    app.MarkerEditField.Value=datestr(x_pos);
                    app.Zoomto30sButton.Enable=true;
                    app.Zoomto10minButton.Enable=true;
                case 'MouseData'
                    disp(app.UIAxes.CurrentPoint);
                    % Properties: Src/EvntName
            end
            
        end
        
        function updateShades(app)
            
            if ~isempty(app.shd_texts), delete(app.shd_texts); end
            if ~isempty(app.dotline), delete(app.dotline); end
            if ~isempty(app.shades),delete(app.shades);end
            app.shades=gobjects(height(app.UITable.Data),1);
            app.shd_texts=gobjects(height(app.UITable.Data),1);
            for itr=1:height(app.UITable.Data)
                %calculate the endpoints of the area-plot and text lable
                area_bnds=[app.UITable.Data{itr,3},app.UITable.Data{itr,4}];
                
                txt_x=area_bnds(1)+(area_bnds(2)-area_bnds(1))/2;
                txt_y=170;
                txt_text=app.UITable.Data{itr,2}; % text lable is the activity
                app.shades(itr)=area(app.UIAxes,area_bnds,[180,180],0,'FaceAlpha',0.2,...
                    'ShowBaseLine','off','EdgeAlpha',0.1, 'HandleVisibility','off' );
                app.shd_texts(itr)=text(app.UIAxes,txt_x,txt_y,txt_text,'FontWeight','bold','FontSize',12,'Color',rgb('Brown'));
            end
            %Move the shades to the background
            children_uix=get(app.UIAxes,'children');
            [~,neworder] = sort(ismember(children_uix,app.shades)); % reorder the handles to move the background lines to the background
            set(app.UIAxes,'children',children_uix(neworder)); % set the new order of all lines
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                movegui(app.ArmElev,'center');
                
                if isdeployed
                    if ~isfolder(fullfile(getenv('APPDATA'),'ArmElevationViewer'))
                        mkdir(fullfile(getenv('APPDATA'),'ArmElevationViewer'))
                    end
                    parmet_fil=fullfile(getenv('APPDATA'),'ArmElevationViewer','parameters.txt');
                    app.logFile=fopen(fullfile(getenv('APPDATA'),'ArmElevationViewer','ErrorLog.txt'),'a');
                else
                    parmet_fil='parameters.txt';
                end
                if isfile(parmet_fil)
                    
                    params=table2struct(readtable(parmet_fil));
                    if ischar(params.cwafile)
                        app.AxivityDataEditField.Value= params.cwafile;
                    end
                    if ~isnan(params.filterF)
                        app.LowPassHzEditField.Value=params.filterF;
                    end
                    if ~isnan(params.lightThreshold)
                        app.LightThresholdluxEditField.Value=params.lightThreshold;
                    end
                end
                plot(app.UIAxes,[datetime(now,'ConvertFrom','datenum')-hours(1),datetime(now,'ConvertFrom','datenum')+hours(1)],0);
                plot(app.UIAxes2,[datetime(now,'ConvertFrom','datenum')-hours(1),datetime(now,'ConvertFrom','datenum')+hours(1)],0);
                app.UIAxes.Tag='myaxis';
            catch excp
                uialert(app.ArmElev,'Error loading program parameters','Errors Occured');
                warn_txt=[datestr(now),' @Startup function: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
        end

        % Button pushed function: OpenfileButton
        function OpenfileButtonPushed(app, event)
            try
                % if no recent file is found set the startup directory to
                % home folder of the current user
                %
                if isempty(app.AxivityDataEditField.Value)
                    datafolder=getenv('HOME');
                else
                    datafolder=app.AxivityDataEditField.Value;
                end
                [cwafile_file,cwafile_path]=uigetfile({'*.cwa','CWA File';'*.wav','WAV File'},'Open Axivity data file in *.CWA or *.WAV format',datafolder);
                figure(app.ArmElev);
                if cwafile_file~=0 %not equal to 0, if dialog is not closed or cancelled
                    [~,~,filext]=fileparts(cwafile_file);
                    if strcmpi(filext,'.cwa')
                        app.ftype=1;
                    elseif strcmpi(filext,'.wav')
                        app.ftype=2;
                    else
                        uialert(app.ArmElev,['Wrong file type: ',app.cwafile],'Errors Occured');
                        warn_txt=[datestr(now),' @FileOpen: wrong file type.'];
                        if isdeployed
                            fprintf(app.logFile,'%s\n',warn_txt);
                        else
                            warning(warn_txt);
                        end
                        return;
                    end
                    app.cwafile=fullfile(cwafile_path,cwafile_file);
                    app.AxivityDataEditField.Value=app.cwafile;
                else
                    return
                end
                prgrs_dlg = uiprogressdlg(app.ArmElev,'Title','Please Wait','Message','Finding Axivity file information','Indeterminate','on');
                
                % Reset buttons,fields and flags
                app.LoaddataButton.Enable=false;
                app.CalculateButton.Enable=false;
                app.Zoomto30sButton.Enable=false;
                app.Zoomto10minButton.Enable=false;
                app.ResetZoomButton.Enable=false;
                app.AddNextActivityButton.Enable=false;
                app.DeleteActivityButton.Enable=false;
                app.MarkStartButton.Enable=false;
                app.MarkEndButton.Enable=false;
                app.MarkZeroReferenceButton.Enable=false;
                app.ExportSummaryButton.Enable=false;
                app.ExportElevationDataButton.Enable=false;
                app.PlotDropDown.Enable=false;
                app.dataLoaded=false;
                app.Calculated=false;
                app.MarkerEditField.Value='';
                app.selectedRow=1;
                
                %clear plots
                if ~isempty(app.lightPks),delete(app.lightPks); end
                if ~isempty(app.shades),delete(app.shades);end
                cla(app.UIAxes);
                cla(app.UIAxes2);
                legend(app.UIAxes,'off');
                legend(app.UIAxes2,'off');
                
                %clear big variables
                app.UITable.Data={};
                app.Sections={};
                app.tdatetime=[];
                
                % Use the openmovement Matlab functions to get *.cwa data file information
                if app.ftype==1
                    app.cwainfo = CWA_readFile(app.cwafile, 'info', 1,'useC', 1);
                elseif app.ftype==2
                    [~,app.cwainfo,status] = readWAVtoACC(app.cwafile,'info');
                    if ~strcmp(status,'OK')
                        uialert(app.ArmElev,['Error reading file: ',app.cwafile],'Errors Occured');
                        warn_txt=[datestr(now),' @FileOpen: ',status];
                        if isdeployed
                            fprintf(app.logFile,'%s\n',warn_txt);
                        else
                            warning(warn_txt);
                        end
                        
                        return;
                    end
                end
                
                app.startTime=app.cwainfo.start.mtime;
                app.stopTime=app.cwainfo.stop.mtime;
                app.selStrTime=app.startTime;
                app.selStpTime=app.stopTime;
                startT = datetime(app.startTime,'ConvertFrom','datenum');
                stopT  = datetime(app.stopTime,'ConvertFrom','datenum');
                tbl_data=table(startT,stopT);
                app.UITable2.Data=tbl_data;
                
                app.LoaddataButton.Enable=true;
                app.StatusLabel.Text='Status: Data file opened. Trim data range if necessary and Load the data';
                close(prgrs_dlg);
                
                
            catch excp
                uialert(app.ArmElev,['Error reading file: ',app.cwafile],'Errors Occured');
                warn_txt=[datestr(now),' @FileOpen: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
                
                app.LoaddataButton.Enable=false;
                close(prgrs_dlg);
            end
        end

        % Close request function: ArmElev
        function ArmElevCloseRequest(app, event)
            try
                if isdeployed
                    parmet_fil=fullfile(getenv('APPDATA'),'ArmElevationViewer','parameters.txt');
                else
                    parmet_fil='parameters.txt';
                end
                
                param_t=table(string(app.AxivityDataEditField.Value),app.LowPassHzEditField.Value,app.LightThresholdluxEditField.Value,'VariableNames',{'cwafile','filterF','lightThreshold'});
                writetable(param_t,parmet_fil);
            catch excp
                uialert(app.ArmElev,['Error saving parameters to: ',parmet_fil] ,'Errors Occured');
                warn_txt=[datestr(now),' @AppExit: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
            if isdeployed
                fclose(app.logFile);
            end
            if ishandle(app.abt_fig)
                if isvalid(app.abt_fig)
                    delete(app.abt_fig);
                end
            end
            delete(app)
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            
            try % It's worth trying! Ain't it?
                prgrs_dlg = uiprogressdlg(app.ArmElev,'Title','Please Wait','Message','Loading Axivity data file','Indeterminate','on');
                
                % Reset buttons,fields and flags
                app.CalculateButton.Enable=false;
                app.Zoomto30sButton.Enable=false;
                app.Zoomto10minButton.Enable=false;
                app.ResetZoomButton.Enable=false;
                app.AddNextActivityButton.Enable=false;
                app.DeleteActivityButton.Enable=false;
                app.MarkStartButton.Enable=false;
                app.MarkEndButton.Enable=false;
                app.MarkZeroReferenceButton.Enable=false;
                app.ExportSummaryButton.Enable=false;
                app.ExportElevationDataButton.Enable=false;
                app.PlotDropDown.Enable=false;
                app.dataLoaded=false;
                app.Calculated=false;
                app.MarkerEditField.Value='';
                app.selectedRow=1;
                
                %clear plots
                if ~isempty(app.lightPks), delete(app.lightPks);end
                if ~isempty(app.shades),delete(app.shades);end
                cla(app.UIAxes);
                cla(app.UIAxes2);
                app.UIAxes2.YGrid=false;
                %clear variables
                app.UITable.Data={};
                app.Sections={};
                app.tdatetime=[];
                
                % Possible sample rates for Axivity AX3
                AX3PosFreq=[12.25,25,50,100,200,400,800,1600,3200];
                
                if app.selStpTime<=app.selStrTime
                    uialert(app.ArmElev,'The Manually selected data range is invalid. Check start and stop times','Errors Occured');
                    return;
                end
                
                app.DeviceID=app.cwainfo.deviceId;
                %% Read *.cwa datafiles of the accelerometers
                %
                
                % open the *.cwa data files using the details found in the respective fileinfo objects
                
                if app.ftype==1
                    % request a 5s early start
                    mtAX3Start=max(app.selStrTime-(5/86400),app.cwainfo.start.mtime);
                    
                    % request a 15s later end
                    mtAX3End=min(app.selStpTime+(15/86400),app.cwainfo.stop.mtime);
                    % call AX3_readfile
                    app.cwadata = CWA_readFile(app.cwafile,'validPackets',app.cwainfo.validPackets,'startTime', mtAX3Start, ...
                        'stopTime', mtAX3End, 'useC', 1);
                    % find sample-frequency of data using 1000 points
                    maxSamples=min(1000,size(app.cwadata.ACC,1));
                    SampleInterval=mean(diff(app.cwadata.ACC(1:maxSamples,1)))*24*60*60; %sampel period T (it is not accurate)
                    %finding true sampel T and sampel f
                    
                    app.Fs=round(1/SampleInterval);
                    [~,index] = min(abs(AX3PosFreq-app.Fs));
                    app.Fs=AX3PosFreq(index); %True f
                    
                    %  Remove any duplicate timestamps
                    app.cwadata.ACC = app.cwadata.ACC(diff(app.cwadata.ACC(:,1))>0,:);
                    
                    % fix start and end to interger second time of mtStart and mtEnd.
                    % Also make sure mtStart and mtEnd lies within the returned data
                    app.selStrTime = round(max(app.selStrTime,app.cwadata.ACC(1,1))*86400)/86400;
                    app.selStpTime = round(min(app.selStpTime,app.cwadata.ACC(end,1))*86400)/86400;
                    
                    % create evenly spaced time axis at given samplerate
                    t = linspace(app.selStrTime, app.selStpTime, (app.selStpTime -  app.selStrTime) * 86400* app.Fs+1);
                    
                    % interpolate data at evenly spaced samples
                    Dintp=zeros(length(t),4);
                    for j=2:4
                        Dintp(:,j) = interp1(app.cwadata.ACC(:,1),app.cwadata.ACC(:,j),t,'pchip',0); %data.ACC must be double if 'cubic' is selected
                    end
                    Dintp(:,1)=t;
                    app.cwadata.ACC=Dintp;
                    % clear big temperorary variables
                    clear t Dintp
                elseif app.ftype==2
                    [app.cwadata,~,status] = readWAVtoACC(app.cwafile,'noinfo',app.selStrTime,app.selStpTime);
                    
                    if ~strcmp(status,'OK')
                        uialert(app.ArmElev,['Error loading file: ''',app.cwafile,'''. Make sure it is exported by Axivity OMGui in WAV format'],'Errors Occured');
                        warn_txt=[datestr(now),' @FileLoad: ',status];
                        if isdeployed
                            fprintf(app.logFile,'%s\n',warn_txt);
                        else
                            warning(warn_txt);
                        end
                        return;
                    end
                    
                    app.selStrTime = round(app.cwadata.ACC(1,1)*86400)/86400; %fix to interger second time
                    app.Fs=app.cwainfo.sampleRate;
                    % Interpolation is not necessary for data read from wav files
                    % because they are evenly sampled. Only time shifting the time axis
                    % such that the first sample is a whole second should be enough
                    app.cwadata.ACC(:,1)=app.cwadata.ACC(:,1)+app.selStrTime-app.cwadata.ACC(1,1);
                    app.selStpTime = app.cwadata.ACC(end,1);
                end
                
                
                %find timings of actual loaded data
                app.tdatetime=datetime(app.cwadata.ACC(:,1),'ConvertFrom','datenum');
                app.t_start=app.tdatetime(1);
                app.t_end=app.tdatetime(end);
                app.t_duration=app.tdatetime(end)-app.tdatetime(1);
                
                %find the inclinations and normalized Acc vector
                [app.normAcc,Inclination]=FindAngles(app.cwadata.ACC(:,2:4),app.Fs,app.LowPassHzEditField.Value);
                
                %plot inclination
                hold(app.UIAxes,'off');
                plot_pts=1:round(app.Fs/app.pts_1s):length(Inclination); % plot only 10 points per second
                plot(app.UIAxes,app.tdatetime(plot_pts),Inclination(plot_pts,:),'DisplayName','X-Inc');
                app.UIAxes.XLimMode='auto';
                app.UIAxes.YLim=[-10,190];
                app.UIAxes.YTick=[0,45,90,135,180];
                app.UIAxes.Title.String='Accelerometer Position';
                %p_acc=plot(app.UIAxes,app.tdatetime,aWxyzt);
                
                xlabel(app.UIAxes,'Time');
                ylabel(app.UIAxes,'X - Inclination (°)');
                hold(app.UIAxes,'on');
                % try to find the peaks in light
                app.cwadata.LIGHT=app.cwadata.LIGHT(diff(app.cwadata.LIGHT(:,1))>0,:);
                dtime_l=app.cwadata.LIGHT(:,1); % the time vector of light in datenum format
                light=app.cwadata.LIGHT(:,2);
                minPkDist= min(seconds(app.t_duration)/2,600);
                [~,locs] = findpeaks(light,dtime_l,'MinPeakProminence',app.LightThresholdluxEditField.Value,'MinPeakDistance',minPkDist/(3600*24),'NPeaks',10);
                
                app.lightPks=gobjects(length(locs),1);% preallocate an array of handles
                if ~isempty(locs)
                    for itr=1:length(locs)
                        x_pos=datetime(locs(itr),'convertfrom','datenum')-seconds(2);
                        if itr>1
                            app.lightPks(itr)=xline(app.UIAxes,x_pos, '--m', 'HandleVisibility','off','Linewidth', 1.2);
                        else
                            app.lightPks(itr)=xline(app.UIAxes,x_pos, '--m', 'DisplayName','LightPks','Linewidth', 1.2);
                        end
                    end
                end
                legend(app.UIAxes);
                %app.UIAxes.Toolbar.Visible = 'off';
                
                
                tbl_data=table({app.DeviceID},"Undefined",app.t_start,app.t_end,app.t_duration,{'(0, 90, 90)'},"","","","","","","","","none");
                app.UITable.Data=tbl_data;
                app.Sections(1).refPos=[1,0,0];
                % Set buttons and flags
                app.dataLoaded=true;
                app.ResetZoomButton.Enable=true;
                app.MarkStartButton.Enable=true;
                app.MarkEndButton.Enable=true;
                app.MarkZeroReferenceButton.Enable=true;
                app.AddNextActivityButton.Enable=true;
                app.ActivityLabel.Text= "Activity:  'Undefined'";
                app.StatusLabel.Text='Status: Data Loaded. Define Start, End, ZeroReference for each activity & press Calculate';
                
                drawnow; %wait for the gui to be updated
                close(prgrs_dlg);
                
            catch excp % gotcha!
                uialert(app.ArmElev,['Error loading file: ',app.cwafile],'Errors Occured');
                warn_txt=[datestr(now),' @FileOpen: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
                close(prgrs_dlg);
                
            end
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
            indices = event.Indices;
            newData = event.NewData;
            try
                
                if indices(2)==3
                    
                    if isnat(newData) || (newData<app.t_start) || (newData>app.t_end)
                        sel_start=app.t_start;
                    else
                        sel_start=newData;
                    end
                    
                    if sel_start>app.UITable.Data{indices(1),4}
                        sel_end=app.t_end;
                    else
                        sel_end= app.UITable.Data{indices(1),4};
                    end
                    
                    sel_duration=sel_end-sel_start;
                    
                    tbl_data=[table(app.UITable.Data{indices(1),1},app.UITable.Data{indices(1),2},...
                        sel_start,sel_end,sel_duration),app.UITable.Data(indices(1),6:15)] ;
                    app.UITable.Data(indices(1),:)=tbl_data;
                    
                elseif indices(2)==4
                    if isnat(newData) || (newData<app.t_start) || (newData>app.t_end)
                        sel_end=app.t_end;
                    else
                        sel_end=newData;
                    end
                    if sel_end<app.UITable.Data{indices(1),3}
                        sel_start=app.t_start;
                    else
                        sel_start= app.UITable.Data{indices(1),3};
                    end
                    
                    sel_duration=sel_end-sel_start;
                    
                    tbl_data=[table(app.UITable.Data{indices(1),1},app.UITable.Data{indices(1),2},...
                        sel_start,sel_end,sel_duration),app.UITable.Data(indices(1),6:15)];
                    app.UITable.Data(indices(1),:)=tbl_data;
                    
                end
                if ~isempty(app.shades)
                    app.updateShades;
                end
                app.ActivityLabel.Text= "Activity:  '"+app.UITable.Data{app.selectedRow,2}+"'";
                if app.Calculated
                    app.StatusLabel.Text='Status: Parameters changed. Press Calculate again to reflect changes';
                end
                
            catch excp
                uialert(app.ArmElev,'Error editing cells. Check data validity.','Errors Occured');
                warn_txt=[datestr(now),' @TableEdit: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
            
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            try % It's worth trying! Ain't it?
                % reset buttons and flags
                app.ExportSummaryButton.Enable=false;
                app.ExportElevationDataButton.Enable=false;
                app.PlotDropDown.Enable=false;
                app.Calculated=false;
                % if in any row start time is greater than end time
                if sum(app.UITable.Data{:,3}>app.UITable.Data{:,4})>=1
                    %warn and exit the calculation
                    uialert(app.ArmElev,'Check Start and End times. Perhaps End time is before the Start time','Start and End selection warning','Icon','info');
                    return;
                end
                prgrs_dlg = uiprogressdlg(app.ArmElev,'Title','Please Wait','Message','Calculating Arm Elevation..','Indeterminate','on');
                
                % Just the name of directions
                n_rows=height(app.UITable.Data);
                
                if ~isempty(app.dotline),delete(app.dotline);end
                
                app.UIAxes.XLimMode='auto';
                app.UIAxes.YLim=[-10,190];
                
                app.PlotDropDown.Items=app.UITable.Data{:,2};
                
                for itr=1:n_rows
                    %read start and stop times from the table
                    start_time=app.UITable.Data{itr,3};
                    end_time=app.UITable.Data{itr,4};
                    
                    %find the corresponding indices
                    indx_start=find(app.tdatetime>=start_time,1,'first');
                    indx_end=find(app.tdatetime<=end_time,1,'last');
                    
                    %calculate time duration in minutes
                    ActivityDur=app.tdatetime(indx_end)-app.tdatetime(indx_start);
                    
                    %Calculate arm elevation angles
                    stepSize=round(app.Fs/app.pts_1s);
                    plot_pts=indx_start:stepSize:indx_end; % plot only 5 points per second
                    normAccSel=app.normAcc(plot_pts,:);
                    app.Sections(itr).tdtime=app.tdatetime(plot_pts);
                    app.Sections(itr).Elevation=acosd(sum(app.Sections(itr).refPos.*normAccSel,2));
                    %app.Sections(itr).Elevation=2*acosd(sqrt(sum((normAccSel-app.Sections(itr).refPos).^2,2))/2);
                    %calculate percentiles and time-durations
                    
                    app.Sections(itr).pcntl50=prctile(app.Sections(itr).Elevation,50);
                    app.Sections(itr).pcntl90=prctile(app.Sections(itr).Elevation,90);
                    
                    true_pts1s=app.Fs/stepSize; % the true pts_1s if Fs is not divisable by pts_1s
                    app.Sections(itr).t30=seconds(sum(app.Sections(itr).Elevation>30)/true_pts1s);
                    app.Sections(itr).t60=seconds(sum(app.Sections(itr).Elevation>60)/true_pts1s);
                    app.Sections(itr).t90=seconds(sum(app.Sections(itr).Elevation>90)/true_pts1s);
                    
                    % update the table with calculated results
                    app.UITable.Data{itr,7}={sprintf('%.2f',app.Sections(itr).pcntl50)};
                    app.UITable.Data{itr,8}={sprintf('%.2f',app.Sections(itr).pcntl90)};
                    app.UITable.Data{itr,9}=duration(app.Sections(itr).t30,'Format','hh:mm:ss');
                    app.UITable.Data{itr,10}={sprintf('%.2f',app.Sections(itr).t30/ActivityDur*100)};
                    app.UITable.Data{itr,11}=duration(app.Sections(itr).t60,'Format','hh:mm:ss');
                    app.UITable.Data{itr,12}={sprintf('%.2f',app.Sections(itr).t60/ActivityDur*100)};
                    app.UITable.Data{itr,13}=duration(app.Sections(itr).t90,'Format','hh:mm:ss');
                    app.UITable.Data{itr,14}={sprintf('%.2f',app.Sections(itr).t90/ActivityDur*100)};
                    
                end
                
                %update activity shades and texts in case they are not
                %updated before pressing the calculate
                if length(app.shades) ~= height(app.UITable.Data)
                    app.updateShades;
                end
                
                % plot the arm elevations for the first activity
                
                plot(app.UIAxes2,app.Sections(1).tdtime,app.Sections(1).Elevation,'Color',rgb('Navy'),'DisplayName','Elevation');
                area_bnds=[app.Sections(1).tdtime(1),app.Sections(1).tdtime(end)];
                hold(app.UIAxes2,'on');
                area(app.UIAxes2,area_bnds,[120,120],60,'FaceColor',rgb('DarkOrange'),'FaceAlpha',0.2,...
                    'ShowBaseLine','off','EdgeAlpha',0.1,'DisplayName','PainArc');
                hold(app.UIAxes2,'off');
                app.UIAxes2.XLimMode='auto';
                app.UIAxes2.YLim=[-10,190];
                app.UIAxes2.YTick=[0,30,60,90,120,150,180];
                app.UIAxes2.YGrid=true;
                legend(app.UIAxes2);
                %app.UIAxes2.Toolbar.Visible = 'off';
                app.PlotDropDown.Value=app.PlotDropDown.Items{1};
                app.UIAxes2.Title.String=['Arm Elevation - ',app.PlotDropDown.Value];
                
                app.ExportSummaryButton.Enable=true;
                app.ExportElevationDataButton.Enable=true;
                app.PlotDropDown.Enable=true;
                app.Calculated=true;
                app.StatusLabel.Text='Status: Elevation Calculated. Export summary and activity data';
                % wait for the plots to be updated
                drawnow;
                close(prgrs_dlg);
            catch excp % gotcha!
                uialert(app.ArmElev,'Error in calculating or plotting Arm Elevation','Errors Occured');
                warn_txt=[datestr(now),' @Calculate: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
                close(prgrs_dlg);
                
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.selectedRow=indices(1);
            app.ActivityLabel.Text= "Activity:  '"+app.UITable.Data{app.selectedRow,2}+"'";
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            try
                if isdeployed
                    parmet_fil=fullfile(getenv('APPDATA'),'ArmElevationViewer','parameters.txt');
                else
                    parmet_fil='parameters.txt';
                end
                
                param_t=table(string(app.AxivityDataEditField.Value),app.LowPassHzEditField.Value,app.LightThresholdluxEditField.Value,'VariableNames',{'cwafile','filterF','lightThreshold'});
                writetable(param_t,parmet_fil);
            catch excp
                uialert(app.ArmElev,['Error saving parameters to: ',parmet_fil] ,'Errors Occured');
                warn_txt=[datestr(now),' @AppExit: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
            if isdeployed
                fclose(app.logFile);
            end
            if ishandle(app.abt_fig)
                if isvalid(app.abt_fig)
                    delete(app.abt_fig);
                end
            end
            delete(app)
        end

        % Button pushed function: ExportSummaryButton
        function ExportSummaryButtonPushed(app, event)
            try
                filename=[num2str(app.DeviceID),' - Arm Elevation Summary.csv'];
                [path,~,~]=fileparts(app.cwafile);
                [file,path,indx] = uiputfile({'*.csv','Elevation Summary CSV (*.csv)';'*.xlsx','Elevation Summary Excel (*.xlsx)'},...
                    'Save Arm Elevation Summary',fullfile(path,filename));
                figure(app.ArmElev);
                if isa(file,'char')
                    switch indx
                        case 1
                            filename=fullfile(path,file);
                            header = app.UITable.ColumnName;
                            Tcell = [header'; table2cell(app.UITable.Data)];
                            writecell(Tcell, filename, 'Delimiter', ',');
                        case 2
                            filename=fullfile(path,file);
                            data_t=app.UITable.Data;
                            writetable(data_t,filename);
                            
                        otherwise
                            uialert(app.ArmElev,'Selected export option is not yet implemented','Export Warning','Icon','info');
                    end
                end
                
            catch excp
                uialert(app.ArmElev,excp.message,'Errors Occured');
                warn_txt=[datestr(now),' @ExportSummary: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
        end

        % Button pushed function: Zoomto30sButton
        function Zoomto30sButtonPushed(app, event)
            try
                if ~isempty(app.MarkerEditField.Value)
                    picked_t=datetime(app.MarkerEditField.Value);
                    zoom_start=min(app.t_end-seconds(15),max(app.t_start,(picked_t-seconds(15))));
                    zoom_end=max(app.t_start+seconds(15),min(app.t_end,(picked_t+seconds(15))));
                    app.UIAxes.XLim=[zoom_start,zoom_end];
                    app.UIAxes.YLim=[-10,190];
                end
            catch excp
                uialert(app.ArmElev,excp.message,'Errors Occured');
                warn_txt=[datestr(now),' @ZoomIn30s: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
            
        end

        % Button pushed function: MarkStartButton
        function MarkStartButtonPushed(app, event)
            try
                if ~strcmp(app.MarkerEditField.Value,'')
                    app.UITable.Data{app.selectedRow,3}=datetime(app.MarkerEditField.Value);
                    temp_dur=app.UITable.Data{app.selectedRow,4}-app.UITable.Data{app.selectedRow,3};
                    if temp_dur>seconds(30)
                        app.UITable.Data{app.selectedRow,5}=app.UITable.Data{app.selectedRow,4}-app.UITable.Data{app.selectedRow,3};
                        if ~isempty(app.shades) && length(app.shades) == height(app.UITable.Data)
                            app.updateShades
                        end
                    end
                    if app.Calculated
                        app.StatusLabel.Text='Status: Parameters changed. Press Calculate again to reflect changes';
                    end
                else
                    uialert(app.ArmElev,'First click in Accelerometer Position curve to define a time','Time Not Defined','Icon','info');
                end
                
            catch excp
                uialert(app.ArmElev,excp.message,'Errors Occured');
                warn_txt=[datestr(now),' @MarkStart: ',excp.messsage];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
        end

        % Button pushed function: MarkEndButton
        function MarkEndButtonPushed(app, event)
            try
                if ~strcmp(app.MarkerEditField.Value,'')
                    cursor=datetime(app.MarkerEditField.Value);
                    if cursor <= app.UITable.Data{app.selectedRow,3}+seconds(30)
                        uialert(app.ArmElev,'End time should at least be 60s later than the Start. Redefine Start and/or End times','Start-Stop time error','Icon','info');
                        return;
                    end
                    
                    app.UITable.Data{app.selectedRow,4}=cursor;
                    app.UITable.Data{app.selectedRow,5}=app.UITable.Data{app.selectedRow,4}-app.UITable.Data{app.selectedRow,3};
                    sel_start=app.UITable.Data{app.selectedRow,3};
                    sel_end=app.UITable.Data{app.selectedRow,4};
                    if sel_start<sel_end
                        %app.UIAxes.XLim=[sel_start,sel_end];
                        %app.UIAxes.YLim=[-10,190];
                        app.updateShades;
                    end
                    
                    if app.Calculated
                        app.StatusLabel.Text='Status: Parameters changed. Press Calculate again to reflect changes';
                    end
                else
                    uialert(app.ArmElev,'First click in Accelerometer Position curve to define a time','Time Not Defined','Icon','info');
                end
            catch excp
                uialert(app.ArmElev,excp.message,'Errors Occured');
                warn_txt=[datestr(now),' @MarkEnd: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
        end

        % Button pushed function: AddNextActivityButton
        function AddNextActivityButtonPushed(app, event)
            try
                if height(app.UITable.Data) >=1
                    n_rows=height(app.UITable.Data);
                    tbl_data=table({app.DeviceID},{sprintf('Undefined-%d',n_rows+1)},app.t_start,app.t_end,app.t_duration,app.UITable.Data{n_rows,6},"","","","","","","","","none");
                    
                    app.UITable.Data(n_rows+1,:)=tbl_data;
                    app.Sections(n_rows+1).refPos=app.Sections(n_rows).refPos;
                    app.selectedRow=n_rows+1;
                    app.ActivityLabel.Text= "Activity:  '"+app.UITable.Data{app.selectedRow,2}+"'";
                    app.DeleteActivityButton.Enable=true;
                    app.ResetZoomButtonPushed(app)
                    if app.Calculated
                        app.StatusLabel.Text='Status: Parameters changed. Press Calculate again to reflect changes';
                    else
                        app.StatusLabel.Text='Status: Activity Added. Define Start, End and ZeroReference';
                    end
                end
            catch excp
                warn_txt=[datestr(now),' @AddActivity: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
        end

        % Button pushed function: DeleteActivityButton
        function DeleteActivityButtonPushed(app, event)
            try
                n_rows=height(app.UITable.Data);
                
                if n_rows >=2
                    % delete the selected activity row from the table and the
                    % Sections struccture
                    app.UITable.Data(app.selectedRow,:)=[];
                    app.Sections(app.selectedRow)=[];
                    if app.selectedRow ~= 1
                        app.selectedRow=app.selectedRow-1;
                        app.ActivityLabel.Text= "Activity:  '"+app.UITable.Data{app.selectedRow,2}+"'";
                    end
                    if n_rows==2
                        app.DeleteActivityButton.Enable=false;
                    end
                    if app.Calculated
                        app.StatusLabel.Text='Status: Parameters changed. Press Calculate again to reflect changes';
                    end
                    app.updateShades;
                end
            catch excp
                warn_txt=[datestr(now),' @DeleteActivity: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
        end

        % Callback function
        function WBVCalcWindowButtonMotion(app, event)
            
        end

        % Button pushed function: ResetZoomButton
        function ResetZoomButtonPushed(app, event)
            try
                app.Zoomto30sButton.Enable=false;
                app.Zoomto10minButton.Enable=false;
                app.UIAxes.XLimMode='auto';
                app.UIAxes.YLim=[-10,190];
                drawnow;
            catch excp
                uialert(app.ArmElev,excp.message,'Errors Occured');
                warn_txt=[datestr(now),' @ResetZoom: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
        end

        % Callback function
        function UITable2CellEdit(app, event)
            
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit2(app, event)
            indices = event.Indices;
            newData = event.NewData;
            try
                if ~isnat(newData)
                    newDT=datenum(newData);
                    if indices(2)==1
                        if  (newDT<app.startTime) || (newDT>app.stopTime)
                            app.selStrTime=app.startTime;
                        else
                            app.selStrTime=newDT;
                        end
                    elseif indices(2)==2
                        if (newDT<app.startTime) || (newDT>app.stopTime)
                            app.selStpTime=app.stopTime;
                        else
                            app.selStpTime=newDT;
                        end
                    end
                    app.UITable2.Data=table(datetime(app.selStrTime,'ConvertFrom','datenum'),datetime(app.selStpTime,'ConvertFrom','datenum'));
                else
                    app.UITable2.Data=table(datetime(app.selStrTime,'ConvertFrom','datenum'),datetime(app.selStpTime,'ConvertFrom','datenum'));
                end
            catch excp
                uialert(app.ArmElev,excp.message,'Errors Occured');
                warn_txt=[datestr(now),' @TrimTable: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
            
        end

        % Button pushed function: MarkZeroReferenceButton
        function MarkZeroReferenceButtonPushed(app, event)
            try
                if(app.dataLoaded)
                    if ~strcmp(app.MarkerEditField.Value,'')
                        refPosStart=datetime(app.MarkerEditField.Value)-seconds(1);
                        refPosStop=datetime(app.MarkerEditField.Value)+seconds(1);
                        indx_start=find(app.tdatetime>=refPosStart,1,'first');
                        indx_end=find(app.tdatetime<=refPosStop,1,'last');
                        app.Sections(app.selectedRow).refPos=mean(app.normAcc(indx_start:indx_end,:),1);
                        app.UITable.Data{app.selectedRow,6}={[sprintf('(%.0f, %.0f, %.0f)',acosd(app.Sections(app.selectedRow).refPos)),...
                            ' - ', datestr(app.MarkerEditField.Value,'[dd-mmm HH:MM:SS]')]};
                        app.CalculateButton.Enable=true;
                        if app.Calculated
                            app.StatusLabel.Text='Status: Parameters changed. Press Calculate again to reflect changes';
                        else
                            app.StatusLabel.Text='Status: Zero Reference defined. Check table and press Calculate or add more activities';
                        end
                    else
                        uialert(app.ArmElev,'First click in Accelerometer Position curve to define a time','Time Not Defined','Icon','info');
                    end
                end
            catch excp
                warn_txt=[datestr(now),' @MarkZeroRef: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            
        end

        % Button pushed function: ExportElevationDataButton
        function ExportElevationDataButtonPushed(app, event)
            try
                prgrs_dlg = uiprogressdlg(app.ArmElev,'Title','Please Wait','Message','Exporting Arm Elevation Data','Indeterminate','on');
                % find the index of currently plotted activity
                ActIndx=find(strcmp(app.PlotDropDown.Items,app.PlotDropDown.Value));
                % predefine file name using the deviceID and activity name
                filename=[sprintf('%d - ',app.DeviceID),app.PlotDropDown.Value,' - Arm Elevation Data.csv'];
                [path,~,~]=fileparts(app.cwafile);
                [file,path,indx] = uiputfile({'*.csv','Elevation data CSV (*.csv)';'*.xlsx','Elevation Data Excel (*.xlsx)';...
                    '*.wav','Elevation data WAV (*.wav)'},'Save Arm Elevation Data',fullfile(path,filename));
                figure(app.ArmElev); %in order to bring back the focus to uifigure
                if isa(file,'char')
                    switch indx
                        case {1,2}
                            filename=fullfile(path,file);
                            tdtimestr=datestr(app.Sections(ActIndx).tdtime,'yy-mm-dd HH:MM:SS.FFF');
                            elev_angle=num2str(app.Sections(ActIndx).Elevation,'%.1f');
                            tbl_Elev=table(tdtimestr,elev_angle,'VariableNames',{'Time','Elevation'});
                            writetable(tbl_Elev,filename);
                        case 3
                            filename=fullfile(path,file);
                            SmplInterval=milliseconds(app.Sections(ActIndx).tdtime(2)-app.Sections(ActIndx).tdtime(1))/1000;
                            wavInfo.Fs=round(1/SmplInterval);
                            wavInfo.Start=datestr(app.Sections(ActIndx).tdtime(1),'yyyy-mm-dd HH:MM:SS.FFF');
                            wavInfo.Stop=datestr(app.Sections(ActIndx).tdtime(end),'yyyy-mm-dd HH:MM:SS.FFF');
                            wavInfo.Scale=180; % angle is the range 0 to 180
                            wavInfo.Activity=app.PlotDropDown.Value;
                            wavInfo.deviceId=app.DeviceID;
                            status=writeElevToWAV(filename,app.Sections(ActIndx).Elevation,wavInfo);
                            if ~strcmp(status,'OK')
                                uialert(app.ArmElev,['Error writing to data to WAV: ',status],'Export Warning','Icon','info');
                                warn_txt=[datestr(now),' @ExportElevation WAV: ',status];
                                if isdeployed
                                    fprintf(app.logFile,'%s\n',warn_txt);
                                else
                                    warning(warn_txt);
                                end
                            end
                            
                        otherwise
                            uialert(app.ArmElev,'Selected export option is not yet implemented','Export Warning','Icon','info');
                    end
                end
                close(prgrs_dlg);
            catch excp
                uialert(app.ArmElev,excp.message,'Errors Occured');
                warn_txt=[datestr(now),' @ExportElevation: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
                close(prgrs_dlg);
            end
        end

        % Value changed function: PlotDropDown
        function PlotDropDownValueChanged2(app, event)
            indx=find(strcmp(app.PlotDropDown.Items,app.PlotDropDown.Value));
            % plot the arm elevations for the first activity
            
            plot(app.UIAxes2,app.Sections(indx).tdtime,app.Sections(indx).Elevation,'Color',rgb('Navy'),'DisplayName','Elevation');
            area_bnds=[app.Sections(indx).tdtime(1),app.Sections(indx).tdtime(end)];
            hold(app.UIAxes2,'on');
            area(app.UIAxes2,area_bnds,[120,120],60,'FaceColor',rgb('DarkOrange'),...
                'FaceAlpha',0.2,'ShowBaseLine','off','EdgeAlpha',0.1,'DisplayName','PainArc');
            hold(app.UIAxes2,'off');
            app.UIAxes2.XLimMode='auto';
            app.UIAxes2.YLim=[-10,190];
            app.UIAxes2.YTick=[0,30,60,90,120,150,180];
            app.UIAxes2.YGrid=true;
            %app.UIAxes2.Toolbar.Visible = 'off';
            app.UIAxes2.Title.String=['Arm Elevation - ',app.PlotDropDown.Value];
            drawnow;
            
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            
            
            app_pos=app.ArmElev.Position;
            abt_pos=[app_pos(1)+app_pos(3)/4,app_pos(2)+app_pos(4)/4,app_pos(3)/2,app_pos(4)/2];
            app.abt_fig = uifigure('Name','About Arm Elevation Viewer','Position',abt_pos,'Resize','off');
            
            uihtml(app.abt_fig,'HTMLSource','Arm Elevation Viewer.htm','Position',[0,0,abt_pos(3),abt_pos(4)]);
            
        end

        % Window button down function: ArmElev
        function ArmElevWindowButtonDown(app, event)
            %Create if statement that determines if the user clicked on the
            %line of the top UIAxes. If they didn't, do nothing
            
            % Properties: Btn/IntersectionPoint/Src/Evnt
            cp=get(app.UIAxes,'CurrentPoint');
            x_pos=num2ruler(cp(1,1),app.UIAxes.XAxis);
            y_pos=cp(1,2);
            x_lim=app.UIAxes.XLim;
            y_lim=app.UIAxes.YLim;
            if app.dataLoaded && x_pos> x_lim(1) && x_pos< x_lim(2) && y_pos > y_lim(1) && y_pos<y_lim(2)
                if ~isempty(app.dotline)
                    delete(app.dotline);
                end
                app.dotline=xline(app.UIAxes,x_pos, '--g', 'Linewidth', 1.2,'DisplayName','Marker');
                app.MarkerEditField.Value=datestr(x_pos);
                app.Zoomto30sButton.Enable=true;
                app.Zoomto10minButton.Enable=true;
                switch app.ArmElev.SelectionType
                    case 'open'
                        app.Zoomto30sButtonPushed;
                    case 'alt'
                        app.UIAxes.XLimMode='auto';
                        app.UIAxes.YLim=[-10,190];
                end
            end
            
        end

        % Value changed function: LowPassHzEditField
        function LowPassHzEditFieldValueChanged(app, event)
           if app.dataLoaded
                app.StatusLabel.Text='Status: Low Pass frequency changed. Press ''Load Data'' again to reload';
           end
        end

        % Value changed function: LightThresholdluxEditField
        function LightThresholdluxEditFieldValueChanged(app, event)
            if app.dataLoaded
                app.StatusLabel.Text='Status: Light threshold changed. Press ''Load Data'' again to reload';
            end
            
        end

        % Button pushed function: Zoomto10minButton
        function Zoomto10minButtonPushed(app, event)
            try
                if ~isempty(app.MarkerEditField.Value)
                    picked_t=datetime(app.MarkerEditField.Value);
                    zoom_start=min(app.t_end-seconds(300),max(app.t_start,(picked_t-seconds(300))));
                    zoom_end=max(app.t_start+seconds(300),min(app.t_end,(picked_t+seconds(300))));
                    app.UIAxes.XLim=[zoom_start,zoom_end];
                    app.UIAxes.YLim=[-10,190];
                end
            catch excp
                uialert(app.ArmElev,excp.message,'Errors Occured');
                warn_txt=[datestr(now),' @ZoomIn10min: ',excp.message];
                if isdeployed
                    fprintf(app.logFile,'%s\n',warn_txt);
                else
                    warning(warn_txt);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ArmElev = uifigure('Visible', 'off');
            app.ArmElev.Name = 'Arm Elevation Viewer';
            app.ArmElev.Icon = 'abt_dlg.png';
            app.ArmElev.CloseRequestFcn = createCallbackFcn(app, @ArmElevCloseRequest, true);
            app.ArmElev.WindowButtonDownFcn = createCallbackFcn(app, @ArmElevWindowButtonDown, true);
            app.FileMenu = uimenu(app.ArmElev);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.ArmElev);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.ArmElev);
            title(app.UIAxes, 'Accelerometer Position')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'X - Inclination (°)')
            app.UIAxes.YLim = [-10 190];
            app.UIAxes.YTick = [0 45 90 135 180];
            app.UIAxes2 = uiaxes(app.ArmElev);
            title(app.UIAxes2, 'Arm Elevation - ')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Elevation (°)')
            app.UIAxes2.YLim = [-10 190];
            app.UIAxes2.YTick = [0 30 60 90 120 150 180];
            app.OpenfileButton = uibutton(app.ArmElev, 'push');
            app.OpenfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButtonPushed, true);
            app.OpenfileButton.Tooltip = {'Open a new Axivity CWA file.'};
            app.OpenfileButton.Text = 'Open file';
            app.UITable = uitable(app.ArmElev);
            app.UITable.ColumnName = {'Device ID'; 'Activity'; 'Start'; 'End'; 'Duration (h:m:s)'; 'Zero Reference (x, y, z)° - [Time]'; 'Elev. Pcntl. 50% (°)'; 'Elev. Pcntl. 90% (°)'; 'Time > 30° (h:m:s)'; 'Perc. T > 30°'; 'Time > 60° (h:m:s)'; 'Perc. T > 60°'; 'Time > 90° (h:m:s)'; 'Perc. T > 90°'; 'Comments'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false false false false false false false false false false false false];
            app.UITable.ColumnEditable = [true true true true false false false false false false false false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CalculationandExportPanel = uipanel(app.ArmElev);
            app.CalculationandExportPanel.Title = 'Calculation and Export';
            app.CalculateButton = uibutton(app.CalculationandExportPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Tooltip = {'Calculates the Elevation angles for the activities defined in the summary table.'};
            app.CalculateButton.Text = 'Calculate';
            app.ExportSummaryButton = uibutton(app.CalculationandExportPanel, 'push');
            app.ExportSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSummaryButtonPushed, true);
            app.ExportSummaryButton.Enable = 'off';
            app.ExportSummaryButton.Tooltip = {'Export elevation summary table to disk'};
            app.ExportSummaryButton.Text = 'Export Summary';
            app.ExportElevationDataButton = uibutton(app.CalculationandExportPanel, 'push');
            app.ExportElevationDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportElevationDataButtonPushed, true);
            app.ExportElevationDataButton.Enable = 'off';
            app.ExportElevationDataButton.Tooltip = {'Export arm elevation data as a time series to disk'};
            app.ExportElevationDataButton.Text = 'Export Elevation Data';
            app.PlotDropDownLabel = uilabel(app.CalculationandExportPanel);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.CalculationandExportPanel);
            app.PlotDropDown.Items = {'Undefined'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged2, true);
            app.PlotDropDown.Enable = 'off';
            app.PlotDropDown.Tooltip = {'Select the Activity for Arm Elevation plotting'};
            app.PlotDropDown.Value = 'Undefined';
            app.AxivityDataEditFieldLabel = uilabel(app.ArmElev);
            app.AxivityDataEditFieldLabel.Text = 'Axivity Data';
            app.AxivityDataEditField = uieditfield(app.ArmElev, 'text');
            app.AxivityDataEditField.Tooltip = {'Selected axivity CWA file'};
            app.DefineStartEndandZeroReferencePanel = uipanel(app.ArmElev);
            app.DefineStartEndandZeroReferencePanel.Title = 'Define Start, End and Zero Reference';
            app.MarkStartButton = uibutton(app.DefineStartEndandZeroReferencePanel, 'push');
            app.MarkStartButton.ButtonPushedFcn = createCallbackFcn(app, @MarkStartButtonPushed, true);
            app.MarkStartButton.Enable = 'off';
            app.MarkStartButton.Tooltip = {'Mark the start position of an actvity'};
            app.MarkStartButton.Text = 'Mark Start';
            app.MarkEndButton = uibutton(app.DefineStartEndandZeroReferencePanel, 'push');
            app.MarkEndButton.ButtonPushedFcn = createCallbackFcn(app, @MarkEndButtonPushed, true);
            app.MarkEndButton.Enable = 'off';
            app.MarkEndButton.Tooltip = {'Mark the end position of the activity'};
            app.MarkEndButton.Text = 'Mark End';
            app.MarkerEditFieldLabel = uilabel(app.DefineStartEndandZeroReferencePanel);
            app.MarkerEditFieldLabel.Text = 'Marker:';
            app.MarkerEditField = uieditfield(app.DefineStartEndandZeroReferencePanel, 'text');
            app.MarkerEditField.Editable = 'off';
            app.MarkerEditField.Tooltip = {'Current cursor position'};
            app.MarkZeroReferenceButton = uibutton(app.DefineStartEndandZeroReferencePanel, 'push');
            app.MarkZeroReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @MarkZeroReferenceButtonPushed, true);
            app.MarkZeroReferenceButton.Enable = 'off';
            app.MarkZeroReferenceButton.Tooltip = {'A  referance Elevation angle is calculated based on mean value of  a two seconds window centered at the current position'};
            app.MarkZeroReferenceButton.Text = 'Mark Zero Reference';
            app.ActivityLabel = uilabel(app.DefineStartEndandZeroReferencePanel);
            app.ActivityLabel.Text = 'Activity:';
            app.AddNextActivityButton = uibutton(app.ArmElev, 'push');
            app.AddNextActivityButton.ButtonPushedFcn = createCallbackFcn(app, @AddNextActivityButtonPushed, true);
            app.AddNextActivityButton.Enable = 'off';
            app.AddNextActivityButton.Text = 'Add Next Activity';
            app.DeleteActivityButton = uibutton(app.ArmElev, 'push');
            app.DeleteActivityButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteActivityButtonPushed, true);
            app.DeleteActivityButton.Enable = 'off';
            app.DeleteActivityButton.Text = 'Delete Activity';
            app.LoaddataButton = uibutton(app.ArmElev, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Enable = 'off';
            app.LoaddataButton.Tooltip = {'Load the Selected CWA file from disk. Trim the data to reduce the load time if necessary.'};
            app.LoaddataButton.Text = 'Load data';
            app.UITable2 = uitable(app.ArmElev);
            app.UITable2.ColumnName = {'Trim-Start'; 'Trim-End'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit2, true);
            app.UITable2.Tooltip = {'Trim data (useful for long measurements)'};
            app.ResetZoomButton = uibutton(app.ArmElev, 'push');
            app.ResetZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ResetZoomButtonPushed, true);
            app.ResetZoomButton.Enable = 'off';
            app.ResetZoomButton.Tooltip = {'Zoom-out to full record'};
            app.ResetZoomButton.Text = 'Reset Zoom';
            app.Zoomto30sButton = uibutton(app.ArmElev, 'push');
            app.Zoomto30sButton.ButtonPushedFcn = createCallbackFcn(app, @Zoomto30sButtonPushed, true);
            app.Zoomto30sButton.Enable = 'off';
            app.Zoomto30sButton.Tooltip = {'Zoom-in to a 30s window in order to easily define markers.'};
            app.Zoomto30sButton.Text = 'Zoom to 30s';
            app.StatusLabel = uilabel(app.ArmElev);
            app.StatusLabel.Text = 'Status: ';
            app.Zoomto10minButton = uibutton(app.ArmElev, 'push');
            app.Zoomto10minButton.ButtonPushedFcn = createCallbackFcn(app, @Zoomto10minButtonPushed, true);
            app.Zoomto10minButton.Enable = 'off';
            app.Zoomto10minButton.Text = 'Zoom to10min';
            app.Panel = uipanel(app.ArmElev);
            app.LowPassHzLabel = uilabel(app.Panel);
            app.LowPassHzLabel.Text = 'Low Pass (Hz)';
            app.LowPassHzEditField = uieditfield(app.Panel, 'numeric');
            app.LowPassHzEditField.Limits = [1 8];
            app.LowPassHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassHzEditFieldValueChanged, true);
            app.LowPassHzEditField.Tooltip = {'The low-pass filter frequency for filtering acceleration signal (1 to 8 Hz). '};
            app.LowPassHzEditField.Value = 5;
            app.LightThresholdluxLabel = uilabel(app.Panel);
            app.LightThresholdluxLabel.Text = 'Light Threshold (lux)';
            app.LightThresholdluxEditField = uieditfield(app.Panel, 'numeric');
            app.LightThresholdluxEditField.Limits = [100 800];
            app.LightThresholdluxEditField.ValueChangedFcn = createCallbackFcn(app, @LightThresholdluxEditFieldValueChanged, true);
            app.LightThresholdluxEditField.Tooltip = {'Intensity of  light at zero-reference compared to background (100 to 800 lux).'};
            app.LightThresholdluxEditField.Value = 400;
            % Show the figure after all components are created
            app.ArmElev.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArmElevation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ArmElev)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ArmElev)
        end
    end
end